classdef UWI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PLOTHISTOGRAMButton             matlab.ui.control.Button
        UNDERWATERIMAGEENHANCEMENTLabel_2  matlab.ui.control.Label
        NoreferenceimageQualityscoreofSharpenedImageEditField  matlab.ui.control.NumericEditField
        NoreferenceimageQualityscoreofSharpenedImageEditFieldLabel  matlab.ui.control.Label
        NoreferenceimageQualityscoreofOriginalImageEditField  matlab.ui.control.NumericEditField
        eferenceimageQualityscoreofOriginalImageLabel  matlab.ui.control.Label
        MeansquarederrorMSEEditField    matlab.ui.control.NumericEditField
        MeansquarederrorMSEEditFieldLabel  matlab.ui.control.Label
        StructuralsimilaritySSIMindexEditField  matlab.ui.control.NumericEditField
        StructuralsimilaritySSIMindexEditFieldLabel  matlab.ui.control.Label
        PeakSNREditField                matlab.ui.control.NumericEditField
        PeakSNREditFieldLabel           matlab.ui.control.Label
        IMAGEQUALITYMATRIXLabel         matlab.ui.control.Label
        TECHNIQUEDropDownLabel          matlab.ui.control.Label
        SignaltoNoiseRatioSNREditField  matlab.ui.control.NumericEditField
        SignaltoNoiseRatioSNREditFieldLabel  matlab.ui.control.Label
        CONTROLSLabel                   matlab.ui.control.Label
        TECHNIQUEDropDown               matlab.ui.control.DropDown
        IterationSlider                 matlab.ui.control.Slider
        IterationSliderLabel            matlab.ui.control.Label
        SigmaKnob                       matlab.ui.control.Knob
        SigmaKnobLabel                  matlab.ui.control.Label
        CorrectionFactorAlphaSlider     matlab.ui.control.Slider
        CorrectionFactorAlphaSliderLabel  matlab.ui.control.Label
        INSERTButton                    matlab.ui.control.Button
        CONVERTButton                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % Description
        Isharp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INSERTButton
        function ImgSelect(app, event)
            [file,path] = uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.image = imread(fullfile(path,file));
            imshow(app.image,'parent',app.UIAxes);
        end

        % Callback function: CONVERTButton, SignaltoNoiseRatioSNREditField
        function convert(app, event)
            rgbImage=double(app.image)/255;
            %grayImage = rgb2gray(rgbImage);
            Ir = rgbImage(:,:,1);
            Ig = rgbImage(:,:,2);
            Ib = rgbImage(:,:,3);

            Ir_mean = mean(Ir, 'all');
            Ig_mean = mean(Ig, 'all');
            Ib_mean = mean(Ib, 'all');
            
            alpha = app.CorrectionFactorAlphaSlider.Value;
            Irc = Ir + alpha*(Ig_mean - Ir_mean);
            Ibc = Ib + alpha*(Ig_mean - Ib_mean);

            I = cat(3, Irc, Ig, Ibc);
            I_lin = rgb2lin(I);
            percentiles = 5;
            illuminant = illumgray(I_lin,percentiles);
            I_lin = chromadapt(I_lin,illuminant,'ColorSpace','linear-rgb');
            Iwb = lin2rgb(I_lin);

            
            sigma = app.SigmaKnob.Value;
            Igauss = Iwb;
            N = app.IterationSlider.Value;
            for iter=1: N
                Igauss =  imgaussfilt(Igauss,sigma);
                Igauss = min(Iwb, Igauss);
            end

            gain = 1;

            Norm = (Iwb-gain*Igauss);

            for n = 1:3
                Norm(:,:,n) = histeq(Norm(:,:,n)); 
            end
            app.Isharp = (Iwb + Norm)/2;

            imshow(app.Isharp,'parent',app.UIAxes2);
            
            Igamma = imadjust(Iwb,[],[],2);
            
            switch app.TECHNIQUEDropDown.Value
                case 'White Balancing'
                    imshow(Iwb,'parent',app.UIAxes4);

                case 'Gamma Correction'
                    imshow(Igamma,'parent',app.UIAxes4);
                
                case 'Normalising'
                    imshow(Norm,'parent',app.UIAxes4);

                case 'Image Sharpening'
                    imshow(app.Isharp,'parent',app.UIAxes4);

                case 'Red Channel Corrected'
                    imshow(Irc,'parent',app.UIAxes4);

                case 'Blue Channel Corrected'
                    imshow(Ibc,'parent',app.UIAxes4);

                otherwise
                    imshow("ERR.png",'parent',app.UIAxes4);
            end
            [peaksnr, snr] = psnr(Igamma,rgbImage);
            app.SignaltoNoiseRatioSNREditField.Value = snr;
            app.PeakSNREditField.Value = peaksnr;

            ssimval = ssim(Igamma,rgbImage);
            app.StructuralsimilaritySSIMindexEditField.Value = ssimval;

            err = immse(Igamma,rgbImage);
            app.MeansquarederrorMSEEditField.Value = err;

            OriginalImage = brisque(rgbImage);
            app.NoreferenceimageQualityscoreofOriginalImageEditField.Value = OriginalImage;

            SharpenedImage = brisque(Igamma);
            app.NoreferenceimageQualityscoreofSharpenedImageEditField.Value = SharpenedImage;
        end

        % Button pushed function: PLOTHISTOGRAMButton
        function PLOTHISTOGRAMButtonPushed(app, event)
            R = app.image(:,:,1);
            G = app.image(:,:,2);
            B = app.image(:,:,3);
            [yRed, x] = imhist(R);
            [yGreen, x] = imhist(G);
            [yBlue, x] = imhist(B);
            plot(app.UIAxes3,x, yRed, 'Red', x, yGreen, 'Green', x, yBlue, 'Blue');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'INPUT IMAGE')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'ENHANCED IMAGE')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'HISTOGRAM')
            xlabel(app.UIAxes3, 'Value of Pixel')
            ylabel(app.UIAxes3, 'Number of Pixels')
            app.UIAxes3.XLim = [0 256];
            app.UIAxes3.XTickLabel = {'0'; '4'; '16'; '64'; '128'; '256'};
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'TECHNIQUE')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CONVERTButton = uibutton(app.UIFigure, 'push');
            app.CONVERTButton.ButtonPushedFcn = createCallbackFcn(app, @convert, true);
            app.CONVERTButton.Text = 'CONVERT';
            app.INSERTButton = uibutton(app.UIFigure, 'push');
            app.INSERTButton.ButtonPushedFcn = createCallbackFcn(app, @ImgSelect, true);
            app.INSERTButton.Text = 'INSERT';
            app.CorrectionFactorAlphaSliderLabel = uilabel(app.UIFigure);
            app.CorrectionFactorAlphaSliderLabel.Text = 'Correction Factor (Alpha)';
            app.CorrectionFactorAlphaSlider = uislider(app.UIFigure);
            app.CorrectionFactorAlphaSlider.Limits = [0 1];
            app.SigmaKnobLabel = uilabel(app.UIFigure);
            app.SigmaKnobLabel.Text = 'Sigma';
            app.SigmaKnob = uiknob(app.UIFigure, 'continuous');
            app.IterationSliderLabel = uilabel(app.UIFigure);
            app.IterationSliderLabel.Text = 'Iteration';
            app.IterationSlider = uislider(app.UIFigure);
            app.TECHNIQUEDropDown = uidropdown(app.UIFigure);
            app.TECHNIQUEDropDown.Items = {'White Balancing', 'Gamma Correction', 'Normalising', 'Image Sharpening', 'Red Channel Corrected', 'Blue Channel Corrected'};
            app.TECHNIQUEDropDown.Value = 'Blue Channel Corrected';
            app.CONTROLSLabel = uilabel(app.UIFigure);
            app.CONTROLSLabel.Text = 'CONTROLS';
            app.SignaltoNoiseRatioSNREditFieldLabel = uilabel(app.UIFigure);
            app.SignaltoNoiseRatioSNREditFieldLabel.Text = 'Signal to Noise Ratio (SNR)';
            app.SignaltoNoiseRatioSNREditField = uieditfield(app.UIFigure, 'numeric');
            app.SignaltoNoiseRatioSNREditField.ValueChangedFcn = createCallbackFcn(app, @convert, true);
            app.TECHNIQUEDropDownLabel = uilabel(app.UIFigure);
            app.TECHNIQUEDropDownLabel.Text = 'TECHNIQUE';
            app.IMAGEQUALITYMATRIXLabel = uilabel(app.UIFigure);
            app.IMAGEQUALITYMATRIXLabel.Text = 'IMAGE QUALITY MATRIX';
            app.PeakSNREditFieldLabel = uilabel(app.UIFigure);
            app.PeakSNREditFieldLabel.Text = 'Peak SNR';
            app.PeakSNREditField = uieditfield(app.UIFigure, 'numeric');
            app.StructuralsimilaritySSIMindexEditFieldLabel = uilabel(app.UIFigure);
            app.StructuralsimilaritySSIMindexEditFieldLabel.Text = 'Structural similarity (SSIM) index';
            app.StructuralsimilaritySSIMindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeansquarederrorMSEEditFieldLabel = uilabel(app.UIFigure);
            app.MeansquarederrorMSEEditFieldLabel.Text = 'Mean-squared error (MSE)';
            app.MeansquarederrorMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.eferenceimageQualityscoreofOriginalImageLabel = uilabel(app.UIFigure);
            app.eferenceimageQualityscoreofOriginalImageLabel.Text = {'No reference image Quality score '; 'of Original Image'};
            app.NoreferenceimageQualityscoreofOriginalImageEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoreferenceimageQualityscoreofSharpenedImageEditFieldLabel = uilabel(app.UIFigure);
            app.NoreferenceimageQualityscoreofSharpenedImageEditFieldLabel.Text = {'No reference image Quality score '; 'of Sharpened Image'};
            app.NoreferenceimageQualityscoreofSharpenedImageEditField = uieditfield(app.UIFigure, 'numeric');
            app.UNDERWATERIMAGEENHANCEMENTLabel_2 = uilabel(app.UIFigure);
            app.UNDERWATERIMAGEENHANCEMENTLabel_2.Text = 'UNDERWATER IMAGE ENHANCEMENT';
            app.PLOTHISTOGRAMButton = uibutton(app.UIFigure, 'push');
            app.PLOTHISTOGRAMButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTHISTOGRAMButtonPushed, true);
            app.PLOTHISTOGRAMButton.Text = 'PLOT HISTOGRAM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UWI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end